classdef maicromouse_sim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StartButton               matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        PauseButton               matlab.ui.control.Button
        FileinputButton           matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        MicroMouseSimulatorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mouse % マウスのオブジェクト
        maze_row_size %迷路の縦マスの数
        maze_col_size %迷路の横マスの数
        maze_row_data %迷路の縦壁のデータ
        maze_col_data %迷路の横壁のデータ
    end


    %func:迷路データをプロット
    %param:アプリ引数、迷路横サイズ、迷路縦サイズ、迷路横データ、迷路縦データ
    %memo:maze_get_dataより得られた迷路情報をプロットする関数
    methods (Access = public)


        function [] = maze_data_plot_for_UI(app,maze_row_size, maze_col_size,maze_row_data, maze_col_data)
        
        maze_step =  9; %迷路の区画幅[cm]
        [poleX,poleY] = meshgrid(0:maze_step:(maze_col_size-1)*maze_step,0:maze_step:(maze_row_size-1)*maze_step);
        plot(app.UIAxes,poleX,poleY,'s','MarkerSize',4,'MarkerEdgeColor',[0 0 0],'MarkerFaceColor',[0.5 0.5 0.5]);
        hold(app.UIAxes,'on')
        
        xlim(app.UIAxes,[0 (maze_col_size-1)*maze_step])
        ylim(app.UIAxes,[0 (maze_row_size-1)*maze_step])
        xticks(app.UIAxes,0:maze_step/2:(maze_col_size-1)*maze_step)
        yticks(app.UIAxes,0:maze_step/2:(maze_row_size-1)*maze_step)
        xticklabels(app.UIAxes,{})
        yticklabels(app.UIAxes,{})
        pbaspect(app.UIAxes,[1 1 1])
        grid(app.UIAxes,'on')

        app.UIAxes.TickLength = [0.0 0.0];
        
        %行方向壁情報プロット
        for i = 1:maze_row_size
            for n = 1:1:(maze_col_size-1)
               if maze_row_data(i,n) == 1
                   plot(app.UIAxes,[n-1,n].*maze_step,[i-1,i-1].*maze_step,'b','LineWidth',1)
               end
            end
        end
        
        %列方向壁情報プロット
        for i = 1:maze_row_size-1
            for n = 1:1:(maze_col_size)
               if maze_col_data(i,n) == 1
                   plot(app.UIAxes,[n-1,n-1].*maze_step,[i-1,i].*maze_step,'b','LineWidth',1)
               end
            end
        end
        %hold(app.UIAxes,'off')
        
        end
        
    %func:マウス画像をロードし、移動オブジェクト化
    %param:[]=(入力座標軸)
    %memo:
        function [] = mouse_load_for_UI(app)
            RGB = imread("manacov2.png");
            [IND,map] = rgb2ind(RGB,8);
            [ysize,xsize,~] = size(IND);
            ycenter = 266; %マウス画像のタイヤ位置
            [x,y]=meshgrid(-xsize/2:1:xsize/2-1,ycenter-ysize:1:ycenter-1);
            z = x .* 0;
            amap = uint8(IND ~= 3).*500;
            
            %surfaceにマウス画像貼り付け
            h = surface(x,y,z,IND,...
                'FaceColor','texturemap',...
                'EdgeColor','none',...
                'CDataMapping','direct',...
                'FaceAlpha','texturemap',...
                'AlphaDataMapping','none',...
                'AlphaData',amap);
            colormap(app.UIAxes,map);
            
            %surfaceを移動オブジェクト化
            app.mouse = hgtransform('Parent',app.UIAxes);
            set(h,'Parent',app.mouse);
            
        end
        
    %func:指定位置、角度にマウスをプロット
    %param:アプリ引数,マウス座標(x,y),マウス角度(rad)
    %memo:        
        function [] = mouse_plot(app,mouse_x,mouse_y,mouse_theta)

            Txy = makehgtform('translate',[mouse_x mouse_y 0]);
            % Z-axis rotation matrix
            Rz = makehgtform('zrotate',mouse_theta);
            % Scaling matrix
            Sxy = makehgtform('scale',1/100);
            % Concatenate the transforms and
            % set the transform Matrix property
            set(app.mouse,'Matrix',Txy*Rz*Sxy);
        
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FileinputButton
        function FileinputButtonPushed(app, event)
            [app.maze_row_size,app.maze_col_size,app.maze_row_data,app.maze_col_data] = maze_data_get;
            maze_data_plot_for_UI(app,app.maze_row_size, app.maze_col_size,app.maze_row_data, app.maze_col_data);
            mouse_load_for_UI(app);
            mouse_plot(app,4.5,4.5,0);

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

        plot(app.UIAxes,4.5,4.5,'b*');
        mouse_plot(app,4.5,9,0);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.FileinputButton = uibutton(app.UIFigure, 'push');
            app.FileinputButton.ButtonPushedFcn = createCallbackFcn(app, @FileinputButtonPushed, true);
            app.FileinputButton.Text = 'File input';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.MicroMouseSimulatorLabel = uilabel(app.UIFigure);
            app.MicroMouseSimulatorLabel.Text = 'Micro Mouse Simulator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maicromouse_sim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end